classdef AppDesignerTutorial2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RefreshButton              matlab.ui.control.Button
        ChooseXandYVariablesLabel  matlab.ui.control.Label
        YaxisDropDown              matlab.ui.control.DropDown
        YaxisDropDownLabel         matlab.ui.control.Label
        XaxisDropDown              matlab.ui.control.DropDown
        XaxisDropDownLabel         matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data
        x2plot
        y2plot% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load import85.mat


% XNew.Properties.VariableNames(1:26) = {'symbolling', 'normalized‐losses',...
%     'wheel‐base', 'length', 'width', 'height', 'curb‐weight', 'engine‐size', ...
%     'bore', 'stroke', 'compression‐ratio', 'horsepower', 'peak‐rpm', ...
%     'city‐mpg', 'highway‐mpg', 'price', 'make', 'fuel‐type', 'aspiration',...
%     'num‐of‐doors', 'body‐style', 'drive‐wheels', 'engine‐location',...
%     'engine‐type', 'num‐of‐cylinders', 'fuel‐system'};


names = {'symbolling', 'normalized_losses',...
    'wheel_base', 'length', 'width', 'height', 'curb_weight', 'engine_size', ...
    'bore', 'stroke', 'compression_ratio', 'horsepower', 'peak_rpm', ...
    'city_mpg', 'highway_mpg', 'price', 'make', 'fuel_type', 'aspiration',...
    'num_of_doors', 'body_style', 'drive_wheels', 'engine_location',...
    'engine_type', 'num_of_cylinders', 'fuel_system'};

for n = 1:length(names)

app.Data.(names{n})= X(:,n);

end


app.XaxisDropDown.Items = names;
app.YaxisDropDown.Items = names;


        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            app.x2plot = app.Data.(value);
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            app.y2plot = app.Data.(value);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            plot(app.UIAxes,app.x2plot, app.y2plot, '--*')%, 'r', 'LineWidth', ...
                %1.5); 
            app.UIAxes.XGrid = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.ChooseXandYVariablesLabel = uilabel(app.UIFigure);
            app.ChooseXandYVariablesLabel.Text = 'Choose X and Y Variables';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDesignerTutorial2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end