classdef ECG_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Patient100234EditField      matlab.ui.control.NumericEditField
        Patient100234Label          matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        EndTimesEditField           matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel      matlab.ui.control.Label
        InitialTimesEditField       matlab.ui.control.NumericEditField
        SmoothingFilterSwitch       matlab.ui.control.Switch
        SmoothingFilterSwitchLabel  matlab.ui.control.Label
        InitialTimesEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Data
        patient
        ecg
        t
        beginX
        endX
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load MIT-BIH-Arrhythmia-Database-MATLAB\.mat
            
for n = 100:234

app.Data.(names{n})= X(:,n);

end

        end

        % Value changed function: InitialTimesEditField
        function InitialTimesEditFieldValueChanged(app, event)
            value = app.InitialTimesEditField.Value;
            app.beginX = app.Data.(value);
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            value = app.EndTimesEditField.Value;
            app.endX = app.Data.(value);
            app.Data.t = {app.beginX:app.endX};
        end

        % Value changed function: SmoothingFilterSwitch
        function SmoothingFilterSwitchValueChanged(app, event)
            value = app.SmoothingFilterSwitch.Value;
            if (value == 1)
            app.Data.ecg = sgolayfilt(app.Data.ecg, 7, 201);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
         if (app.InitialTimesEditField.Value == 0 || app.EndTimesEditField.Value == 0)
          
        
         end

            plot(app.UIAxes,app.Data.t, app.Data.ecg)%, 'r', 'LineWidth', ...
                %1.5); 
            app.UIAxes.XGrid = 'on';   
        

            [~, locs_Rwave] = findpeaks(app.Data.ecg, 'MinPeakHeight', 2, ...
            'MinPeakDistance', 1800);
            ECG_Inverted = -(app.Data.ecg);

            [~, locs_Swave] = findpeaks(ECG_Inverted, 'MinPeakHeight', -0.8, ...
            'MinPeakDistance', 1800);
            hold on;
            plot(app.Data.t(locs_Rwave), app.Data.ecg(locs_Rwave), 'rv', 'MarkerFaceColor', 'r')
            plot(app.Data.t(locs_Swave), app.Data.ecg(locs_Swave), 'rs', 'MarkerFaceColor', 'b')
    
    Rtimes1 = app.Data.t(app.Data.locs_Rwave); % Convert to time in seconds
    Rperiod1 = diff(Rtimes1); % Convert to period
    Rfreq1 = 1/mean(Rperiod1); 
    average1 = Rfreq1*60;
        end

        % Callback function
        function PatientDropDownValueChanged(app, event)
           
        end

        % Value changed function: Patient100234EditField
        function Patient100234EditFieldValueChanged(app, event)
            value = app.Patient100234EditField.Value;
            app.patient = 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InitialTimesEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTimesEditFieldLabel.Text = 'Initial Time [s]';
            app.SmoothingFilterSwitchLabel = uilabel(app.UIFigure);
            app.SmoothingFilterSwitchLabel.Text = 'Smoothing Filter';
            app.SmoothingFilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.SmoothingFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothingFilterSwitchValueChanged, true);
            app.InitialTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTimesEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesEditFieldLabel.Text = 'End Time [s]';
            app.EndTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.Patient100234Label = uilabel(app.UIFigure);
            app.Patient100234Label.Text = 'Patient # [100-234]';
            app.Patient100234EditField = uieditfield(app.UIFigure, 'numeric');
            app.Patient100234EditField.ValueChangedFcn = createCallbackFcn(app, @Patient100234EditFieldValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end