classdef ECG_GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SmoothingFilterButton          matlab.ui.control.StateButton
        SavePlotButton                 matlab.ui.control.Button
        AverageHeartRateTextArea       matlab.ui.control.TextArea
        AverageHeartRateTextAreaLabel  matlab.ui.control.Label
        PatientDropDown                matlab.ui.control.DropDown
        PatientDropDownLabel           matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        EndTimesEditField              matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel         matlab.ui.control.Label
        InitialTimesEditField          matlab.ui.control.NumericEditField
        InitialTimesEditFieldLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        patient
        ecg
        t
        average
        beginX
        endX
        count
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
cd F:\MATLAB\MIT-BIH-Arrhythmia-Database-MATLAB\ECGData\
csvFiles = [dir('*.csv')];
cd ../

for jj = 1:length(csvFiles)
cd F:\MATLAB\MIT-BIH-Arrhythmia-Database-MATLAB\ECGData\
currentname = csvFiles(jj).name;
T=readtable(currentname);
x=T{:,:};
val = x';
matFileName = strcat(currentname(1:4), '.mat');
cd F:\MATLAB\MIT-BIH-Arrhythmia-Database-MATLAB\MIT-BIH-Arrhythmia-Database-MATLAB\
save(matFileName,'val')
end

patientfiles = [dir('*.mat')];

for ii = 1:length(patientfiles)
   currentpatientname = patientfiles(ii).name;
   currentpatient = currentpatientname(1:4);
   patientdata{ii} = strcat('P', currentpatient);
end


app.PatientDropDown.Items = patientdata;

app.count = 0;

        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged2(app, event)
            value = app.PatientDropDown.Value;
            app.patient = value;
        end

        % Value changed function: InitialTimesEditField
        function InitialTimesEditFieldValueChanged(app, event)
            value = app.InitialTimesEditField.Value;
            app.beginX = value;
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            value = app.EndTimesEditField.Value;
            app.endX = value;
            
        end

        % Value changed function: SmoothingFilterButton
        function SmoothingFilterButtonValueChanged(app, event)
           app.count = app.count + 1;
   
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
        [app.t, app.ecg, Fs] = LoadMITecg(app.patient(2:end));
            
         if (app.EndTimesEditField.Value ~= 0)
             
             app.t = app.t(app.beginX:app.endX);
             app.ecg = app.ecg(app.beginX:app.endX);
        
         end

         if (mod(app.count,2) == 1)
            app.ecg = sgolayfilt(app.ecg, 7, 41);
         end


         plot(app.UIAxes,app.t, app.ecg, 'k') 
         hold(app.UIAxes, 'on')
         app.UIAxes.XGrid = 'on';   

        
            [~, locs_Rwave] = findpeaks(app.ecg, 'MinPeakHeight', 0.2, ...
            'MinPeakDistance', 100);

            ECG_Inverted = -(app.ecg);
            [~, locs_Swave] = findpeaks(ECG_Inverted, 'MinPeakHeight', -0.2, ...
            'MinPeakDistance', 175);

            plot(app.UIAxes, app.t(locs_Rwave), app.ecg(locs_Rwave), 'rv', 'MarkerFaceColor', 'r')
            
            plot(app.UIAxes, app.t(locs_Swave), app.ecg(locs_Swave), 'rs', 'MarkerFaceColor', 'b')

    
        Rtimes = app.t(locs_Rwave); % Convert to time in seconds
        Rperiod = diff(Rtimes); % Convert to period
        Rfreq = 1/mean(Rperiod); 
        app.average = int2str(Rfreq*60);
        app.AverageHeartRateTextArea.Value = strcat(app.average, ' BPM');
        hold(app.UIAxes, 'off')
        end

        % Value changed function: AverageHeartRateTextArea
        function AverageHeartRateTextAreaValueChanged(app, event)

            
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
             cd F:\MATLAB\MIT-BIH-Arrhythmia-Database-MATLAB\PlotImage\

             figure(1)
             plot(app.t, app.ecg, 'k') 
             hold on
             [~, locs_Rwave] = findpeaks(app.ecg, 'MinPeakHeight', 0.2, ...
            'MinPeakDistance', 100);

            ECG_Inverted = -(app.ecg);
            [~, locs_Swave] = findpeaks(ECG_Inverted, 'MinPeakHeight', -0.2, ...
            'MinPeakDistance', 175);

            plot(app.t(locs_Rwave), app.ecg(locs_Rwave), 'rv', 'MarkerFaceColor', 'r')
            
            plot(app.t(locs_Swave), app.ecg(locs_Swave), 'rs', 'MarkerFaceColor', 'b')
            xlabel('Time [s]')
            ylabel('Voltage [mV]')
            legend(strcat(app.average, ' BPM'))
            hold off
            imageName = strcat(app.patient,int2str(app.beginX),'_',int2str(app.endX));
            saveas(figure(1), imageName, 'png')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InitialTimesEditFieldLabel = uilabel(app.UIFigure);
            app.InitialTimesEditFieldLabel.Text = 'Initial Time [s]';
            app.InitialTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialTimesEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimesEditFieldLabel.Text = 'End Time [s]';
            app.EndTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PatientDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.UIFigure);
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged2, true);
            app.AverageHeartRateTextAreaLabel = uilabel(app.UIFigure);
            app.AverageHeartRateTextAreaLabel.Text = 'Average Heart Rate';
            app.AverageHeartRateTextArea = uitextarea(app.UIFigure);
            app.AverageHeartRateTextArea.ValueChangedFcn = createCallbackFcn(app, @AverageHeartRateTextAreaValueChanged, true);
            app.SavePlotButton = uibutton(app.UIFigure, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.SmoothingFilterButton = uibutton(app.UIFigure, 'state');
            app.SmoothingFilterButton.ValueChangedFcn = createCallbackFcn(app, @SmoothingFilterButtonValueChanged, true);
            app.SmoothingFilterButton.Text = 'Smoothing Filter';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_GUI_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end